classdef with_encoder_motion_control_experiment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        fillbehaviorfilefordebuggingButton  matlab.ui.control.Button
        LoadExperimentFileButton      matlab.ui.control.StateButton
        LoadPairedPassiveBlockButton  matlab.ui.control.Button
        ExperimentControlPanel        matlab.ui.container.Panel
        PracticetrialsButton          matlab.ui.control.StateButton
        StopButton                    matlab.ui.control.StateButton
        PauseButton                   matlab.ui.control.StateButton
        RunButton                     matlab.ui.control.StateButton
        CurrentArmMovementNALabel     matlab.ui.control.Label
        TestEncoderButton             matlab.ui.control.Button
        MotorControlPanel             matlab.ui.container.Panel
        LoadMotorsCheckBox            matlab.ui.control.CheckBox
        LinearStageMotorSwitch        matlab.ui.control.Switch
        LinearStageMotorSwitchLabel   matlab.ui.control.Label
        CreateTrialsButton            matlab.ui.control.StateButton
        TrialhistoryTextArea          matlab.ui.control.TextArea
        TrialhistoryTextAreaLabel     matlab.ui.control.Label
        ExperimentFileNALabel         matlab.ui.control.Label
        GeneratetrialsButtonGroup     matlab.ui.container.ButtonGroup
        SpeedButton                   matlab.ui.control.RadioButton
        DirectionButton               matlab.ui.control.RadioButton
        CentermotorsPanel             matlab.ui.container.Panel
        MovefullyupButton             matlab.ui.control.StateButton
        SetzeropointButton            matlab.ui.control.StateButton
        Set90Button                   matlab.ui.control.StateButton
        IndentEditField               matlab.ui.control.NumericEditField
        IndentEditFieldLabel          matlab.ui.control.Label
        MoveUpDownButton              matlab.ui.control.StateButton
        RotatemotorButton             matlab.ui.control.StateButton
        RotationEditField             matlab.ui.control.NumericEditField
        RotationEditFieldLabel        matlab.ui.control.Label
        ParticipantsIDEditField       matlab.ui.control.EditField
        ParticipantsIDEditFieldLabel  matlab.ui.control.Label
        MovestagePanel                matlab.ui.container.Panel
        SetstartPosButton             matlab.ui.control.StateButton
        MoveButton                    matlab.ui.control.StateButton
        DirectionButtonGroup          matlab.ui.container.ButtonGroup
        RightButton                   matlab.ui.control.RadioButton
        LeftButton                    matlab.ui.control.RadioButton
        StepsEditField                matlab.ui.control.NumericEditField
        StepsEditFieldLabel           matlab.ui.control.Label
        SpeedEditField                matlab.ui.control.NumericEditField
        SpeedEditFieldLabel           matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fastMotionCommanded = ExpConfig.fast_target_speed_steps;
        slowMotionCommanded = ExpConfig.slow_target_speed_steps; 
        moveDurSec = ExpConfig.StageMotionDur_sec;
        stimLagSec = ExpConfig.stimLag_sec;
        encoderThreshold_cm = ExpConfig.encoderThreshold_cm; % how far should the arm move to consider it motion 
        minTravel = 0;
        maxTravel = 3640%2998;% ExpConfig.LinStage_range_steps;%the lengh of the max speed in steps


        %----------------------
        maxSpinRate = 1023;
        maxSpin_perDeg = 361.4;
        baseActuatorPos = 900;
        maxActuatorPos = 2100;
        maxActuatorDisp = 26; %in mm
        
        %----------------------
        motorsLoaded = 0;        
        fileLoaded = [];
        curFolder = [];
        participantID = [];
        filesFolder = [];
        fileName = [];
        cam
        outputVideo
        vidname
        videoFolder
        TrialIndex = 0
        curPairId = 0

        %----------------------
        %variables controlling arduino devices
        rotation_and_spin_motor = [];
        indentationActuator = [];
        LinearStage_motor = [];
        rotaryEncoder = [];


        rotation_and_spin_motor_comPort = 'COM4'; %uno arduino
        indentationActuator_comPort = 'COM10'; %leo arduino
        LinearStage_motor_comPort = 'COM7';
        rotaryEncoder_comPort = 'COM5'; % example

        rotation_and_spin_motor_Rate = 115200;
        indentationActuator_Rate = 9600;
        LinearStage_motor_Rate = 115200;
        rotaryEncoder_Rate = 115200;
        %----------------------
       
        mycurLoc = [];
        mycurStartLoc = [];
        indentZero = [];
        rotationCtrAngle = [];
        curIndent = [];
        curAngle = 0;
        newAngle = nan;
        stopGUI = [];
    end
    
    methods (Access = private)
        % ---------------------  ACTIVE TRIAL FUNCTIONS  ---------------------

    function printStartTrial(app,windowPtr)
        textString = 'Start Trial';
        DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
        Screen('Flip', windowPtr);
        WaitSecs(1);
    end
    
    function printActiveMotionCue(app, windowPtr, w, w1, no_motion_flag)
        % DISPLAY Motion Direction cue to subject
        % Active trial, motor off (subject moves)
        if ~no_motion_flag
            textString = sprintf('Move your arm %s with %s speed', w, w1);
        else
            textString = 'Keep you arm in the same position';
        end
        DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
        Screen('Flip', windowPtr);
        WaitSecs(2);
    end
    
    function printResponsePrompt(app, windowPtr)
        %---- DISPLAY response prompt starting when movement begins
        textString = 'Left or Right Response';
        DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
        Screen('Flip', windowPtr);
        %------
    end
    
    function printCenteredMessage(app, windowPtr, textString)
        DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
        Screen('Flip', windowPtr);
    end
    
    function [w, w1, no_motion_flag] = getTrialInfo(app, stepsStage, speedStage)
        % Get trial info
        no_motion_flag = false;
    
        if stepsStage < 0
            w = 'right';
        elseif stepsStage > 0
            w = 'left';
        else
            w = 'none';
            no_motion_flag = true;
        end

        if speedStage == ExpConfig.fast_target_speed_steps
            w1 = 'fast';
        elseif speedStage == ExpConfig.slow_target_speed_steps
            w1 = 'slow';
        elseif speedStage == 0
            w1 = 'no';
        end
    
        % if speedStage >= ExpConfig.fast_target_speed_steps*0.8
        %     w1 = 'fast';
        % elseif speedStage < ExpConfig.slow_target_speed_steps*1.2 && speedStage > 0
        %     w1 = 'slow';
        % elseif speedStage == 0
        %     w1 = 'no';
        % end
    end
    
    function myTrialHistory = printTrialInfo(app, myTrialHistory, n, w, w1, myAngle)
        myText = ['#' num2str(n) ' Arm moves ' w ' with ' w1 ' speed and wheel angle ' ...
            num2str(myAngle) 'º'];
        myTrialHistory{end+1,1} = myText;
        app.TrialhistoryTextArea.Value = myTrialHistory;
        scroll(app.TrialhistoryTextArea, 'bottom');
        drawnow
    end
    
    function myTrialHistory = printResponseInfo(app, myTrialHistory, buttonPushed)
        if buttonPushed == 1
            myText = '  Left Response';
        elseif buttonPushed == 3
            myText = '  Right Response';
        else
            myText = '  N/A';
        end
    
        myTrialHistory{end+1,1} = myText;
        app.TrialhistoryTextArea.Value = myTrialHistory;
        scroll(app.TrialhistoryTextArea, 'bottom');
        drawnow
    end
    

    function [buttonPushed, respTime] = waitForResponse(app, windowPtr, startStim)
        % Helper function to wait for a mouse response after the stimulus period
        % if no response was given yet.
        while true
            [~, ~, buttons] = GetMouse;
            if any(buttons)
                buttonPushed = find(buttons ~= 0, 1);
                respTime = GetSecs - startStim;
                printCenteredMessage(app, windowPtr, 'Wait...');
                return
            end
    
            drawnow limitrate
            if app.stopGUI == 1
                app.stopGUI = 0;
                error('Experiment stopped');
            end
        end
    end

    function [saveDir, outName] = getBehaviorSavePath(app)

        saveDir = fullfile(app.curFolder, [app.participantID '_data']);
    
        if ~isfolder(saveDir)
            mkdir(saveDir);
        end
    
        [~, baseName, ext] = fileparts(app.fileName);
        if isempty(ext)
            ext = '.mat';
        end
    
        behaviorFileName = [baseName '_behavior' ext];
        outName = fullfile(saveDir, behaviorFileName);
    end
    function printMoveForDurationCue(app, windowPtr, w, moveDurSec)
        textString = sprintf('Keep moving your arm %s for %.1f seconds', w, moveDurSec);
        DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
        Screen('Flip', windowPtr);
    end
    
    function printResponsePromptWithMoveCue(app, windowPtr, w, moveDurSec)
        textString = sprintf(['Keep moving your arm %s for %.1f seconds\n\n' ...
                              'Left or Right Response'], w, moveDurSec);
        DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
        Screen('Flip', windowPtr);
    end
    function drawActiveMovementCountdown(app, windowPtr, w, moveDurSec, startArmMov, showResponsePrompt)
        elapsed = GetSecs - startArmMov;
        if elapsed > moveDurSec
            elapsed = moveDurSec;
        elseif elapsed < 0
            elapsed = 0;
        end
    
        remaining = max(0, moveDurSec - elapsed);
        frac = min(1, elapsed / moveDurSec);
    
        Screen('FillRect', windowPtr, [0 0 0]);
    
        [screenX, screenY] = Screen('WindowSize', windowPtr);
    
        Screen('TextFont', windowPtr, 'Arial');
        Screen('TextSize', windowPtr, 28);
        Screen('TextStyle', windowPtr, 0);
    
        centerY = screenY / 2;
    
        instrY = round(centerY - 140);
        respY  = round(centerY - 60);
        timerY = round(centerY + 20);
        barY   = round(centerY + 120);
    
        DrawFormattedText(windowPtr, sprintf('Keep moving your arm %s', w), ...
            'center', instrY, [255 255 255]);
    
        if showResponsePrompt
            DrawFormattedText(windowPtr, 'Left or Right Response', ...
                'center', respY, [255 255 255]);
        end
    
        DrawFormattedText(windowPtr, sprintf('Time left: %.1f s', remaining), ...
            'center', timerY, [255 255 255]);
    
        barW = 500;
        barH = 24;
        barX = round((screenX - barW) / 2);
    
        Screen('FrameRect', windowPtr, [255 255 255], ...
            [barX, barY, barX + barW, barY + barH], 2);
    
        Screen('FillRect', windowPtr, [255 255 255], ...
            [barX, barY, barX + round(barW * frac), barY + barH]);
    
        Screen('Flip', windowPtr);
    end
    
    function [trialRow, myTrialHistory] = runActiveTrial(app, windowPtr, trialRow, n, expTime_start, myTrialHistory)
        % ---------------------  PREPARE  ---------------------

        resetSpeedDisplay(app);
    
        myAngle = trialRow.StimDirection;
        [app.curAngle, flipSpeed] = correctDirection(app.rotation_and_spin_motor, app.curAngle, myAngle);
    
        accStage = trialRow.Arm_Mov_Acc_arduino;
        stepsStage = trialRow.Arm_Mov_Steps_arduino;
        speedStage = trialRow.Arm_Mov_Speed_arduino;
    
        [w, w1, no_motion_flag] = getTrialInfo(app, stepsStage, speedStage);
        myTrialHistory = printTrialInfo(app, myTrialHistory, n, w, w1, myAngle);
    

        stimStarted = false;
        indentMovedDown = false;
    
        buttonPushed = [];
        respTime = [];
        encoderSamples = [];
        startStim = nan;
        stimEndTime = nan;
    
        % ---------------------  TRIAL ONSET  ---------------------
        startTrial = GetSecs;
        trialRow.TrialStart_time = startTrial - expTime_start;
    
        printStartTrial(app, windowPtr);
        printActiveMotionCue(app, windowPtr, w, w1, no_motion_flag);
    
        if no_motion_flag
            startArmMov = GetSecs;
            trialRow.Arm_Mov_Onset = startArmMov - expTime_start;
        else
            [startArmMov, onsetSample] = waitForEncoderMotion(app, app.encoderThreshold_cm);
            if isempty(onsetSample)
                encoderSamples = [];
            else
                encoderSamples = onsetSample;
            end
            trialRow.Arm_Mov_Onset = startArmMov - expTime_start;
            drawActiveMovementCountdown(app, windowPtr, w, app.moveDurSec, startArmMov, false);
        end
    
        stimDurSec = trialRow.StimDuration_arduino / 1000;
    
        while true
            elapsed = GetSecs - startArmMov;
    
            if ~stimStarted && elapsed >= app.stimLagSec
                write(app.rotation_and_spin_motor, sprintf("%d %d %d\n", 3, ...
                    flipSpeed * trialRow.StimSpeed_arduino, ...
                    trialRow.StimDuration_arduino), "string");
    
                startStim = GetSecs;
                trialRow.StimOnset = startStim - expTime_start;
                stimEndTime = startStim + stimDurSec;
                stimStarted = true;
    
                indentLoc = app.curIndent;
                write(app.indentationActuator, sprintf("%d %d\n", 1, indentLoc), "string");
                indentMovedDown = true;
            end
    
            if no_motion_flag
                if stimStarted
                    printResponsePrompt(app, windowPtr);
                end
            else
                drawActiveMovementCountdown(app, windowPtr, w, app.moveDurSec, startArmMov, stimStarted);
            end

            % collect encoder from arm movement onset onward
            newSamples = readAvailableEncoderSamples(app);
            if ~isempty(newSamples)
                encoderSamples = [encoderSamples; newSamples]; 
                updateCurrentSpeedDisplay(app, encoderSamples);
            end
    
            if stimStarted
           
    
                [~, ~, buttons] = GetMouse;
                if any(buttons)
                    buttonPushed = find(buttons ~= 0, 1);
                    respTime = GetSecs - startStim;
                    printCenteredMessage(app, windowPtr, 'Wait...');
                    break;
                end
    
                if GetSecs >= stimEndTime
                    break;
                end
            end
    
            if ~no_motion_flag && elapsed >= app.moveDurSec && ~stimStarted
                break;
            end
    
            drawnow limitrate
            if app.stopGUI == 1
                app.stopGUI = 0;
                error('Experiment stopped');
            end
        end
    
        if indentMovedDown
            indentLoc = app.indentZero;
            write(app.indentationActuator, sprintf("%d %d\n", 1, indentLoc), "string");
        end
    
        trialRow.MeasuredSpeed_cm_s = computeAverageEncoderVelocity(app, encoderSamples);
        trialRow.EncoderSamples = {encoderSamples};
        updateSpeedHistogram(app, encoderSamples);
    
        if stimStarted && isempty(buttonPushed)
            [buttonPushed, respTime] = waitForResponse(app, windowPtr, startStim);
        elseif ~stimStarted
            trialRow.StimOnset = nan;
        end
    
        trialRow.Response = buttonPushed;
        trialRow.ReactionTime = respTime;
        myTrialHistory = printResponseInfo(app, myTrialHistory, buttonPushed);
    
        % curClock = GetSecs;
        % while (GetSecs - curClock) < 0.5
        %     drawnow limitrate
        %     if app.stopGUI == 1
        %         app.stopGUI = 0;
        %         error('Experiment stopped');
        %     end
        % end
    end

    
    % ---------------------  ENCODER HELPERS  ---------------------
    
    function flushRotaryEncoderBuffer(app)
        % Helper function to clear any unread encoder bytes from the serial buffer.
        % This prevents stale lines from a previous trial from being used in the new trial.
        if ~isempty(app.rotaryEncoder) && app.rotaryEncoder.NumBytesAvailable > 0
            read(app.rotaryEncoder, app.rotaryEncoder.NumBytesAvailable, "char");
        end
    end
    
    function zeroRotaryEncoder(app)
        % Helper function to zero the encoder position at the current arm location.
        % The Arduino listens for the command "ZERO" and resets its zero reference.
        writeline(app.rotaryEncoder, "ZERO");
    end
    
    function sample = readEncoderSample(app)
        % Helper function to read one encoder sample from the Arduino serial stream.
        % Expected Arduino line format:
        % DATA,time_ms,count,distance_cm
        % If no valid sample is available, this function returns [].
        sample = [];
    
        if isempty(app.rotaryEncoder)
            return
        end
    
        if app.rotaryEncoder.NumBytesAvailable <= 0
            return
        end
    
        line = readline(app.rotaryEncoder);
        toks = split(strtrim(line), ",");
    
        if numel(toks) == 4 && strcmp(toks(1), "DATA")
            sample.t_ms = str2double(toks(2));
            sample.count = str2double(toks(3));
            sample.dist_cm = str2double(toks(4));
        end
    end
    
    function [startArmMov, onsetSample] = waitForEncoderMotion(app, threshold_cm)
            startArmMov = NaN;
            onsetSample = [];
        
            flushRotaryEncoderBuffer(app);
            zeroRotaryEncoder(app);
            pause(0.05);
        
            while isnan(startArmMov)
                samples = readAvailableEncoderSamples(app);
        
                if ~isempty(samples)
                    idx = find(abs(samples(:,3)) >= threshold_cm, 1, 'first');
        
                    if ~isempty(idx)
                        startArmMov = GetSecs;
                        onsetSample = samples(idx,:);   % [t_ms count dist_cm]
                        break;
                    end
                end
        
                drawnow limitrate
                if app.stopGUI == 1
                    app.stopGUI = 0;
                    error('Experiment stopped');
                end
            end
    end
    
    function avgVel_cm_s = computeAverageEncoderVelocity(app, encoderSamples)
        if size(encoderSamples, 1) < 2
            avgVel_cm_s = NaN;
            return
        end
    
        dt = diff(encoderSamples(:,1)) ./ 1000;
        dx = diff(encoderSamples(:,3));
    
        vel_cm_s = dx ./ dt;
        vel_cm_s(dt <= 0) = NaN;
    
        avgVel_cm_s = mean(abs(vel_cm_s), 'omitnan');
    end
        
    function samples = readAvailableEncoderSamples(app)
        samples = [];
    
        if isempty(app.rotaryEncoder)
            return
        end
    
        while app.rotaryEncoder.NumBytesAvailable > 0
            line = strtrim(readline(app.rotaryEncoder));
            toks = split(line, ",");
    
            if numel(toks) == 4 && strcmp(strtrim(toks{1}), "DATA")
                t_ms    = str2double(toks{2});
                count   = str2double(toks{3});
                dist_cm = str2double(toks{4});
    
                if ~any(isnan([t_ms, count, dist_cm]))
                    samples(end+1,:) = [t_ms, count, dist_cm]; %#ok<AGROW>
                end
            end
        end
    end
      
    % ---------------------  PASSIVE TRIAL FUNCTIONS  ---------------------
    function printPassiveMotionCue(app, windowPtr, w, w1, no_motion_flag)
    % DISPLAY Motion Direction cue to subject
    % Passive trial, motor on (arm moved by motor)

        if ~no_motion_flag
            textString = sprintf('Arm will be moved %s with %s speed', w, w1);
        else
            textString = 'Arm will not be moved';
        end
    
        DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
        Screen('Flip', windowPtr);
        WaitSecs(1);
    end

    function [trialRow, myTrialHistory] = runPassiveTrial(app, windowPtr, trialRow, n, expTime_start, myTrialHistory)
    % ---------------------  PREPARE  ---------------------
            try
                resetSpeedDisplay(app);
        
                % ROTATE stimulus to the correct direction - PREP
                myAngle = trialRow.StimDirection;
                [app.curAngle, flipSpeed] = correctDirection(app.rotation_and_spin_motor, app.curAngle, myAngle);
        
                % GET the speed, acceleration, and steps for the linear stage
                speedStage = trialRow.Arm_Mov_Speed_arduino;
                accStage   = trialRow.Arm_Mov_Acc_arduino;
                stepsStage = trialRow.Arm_Mov_Steps_arduino;
        
                % GET trial info
                [w, w1, no_motion_flag] = getTrialInfo(app, stepsStage, speedStage);
        
                % PRINT trial information in the app box - DEBUG
                myTrialHistory = printTrialInfo(app, myTrialHistory, n, w, w1, myAngle);
        
                buttonPushed = [];
                respTime = [];
                encoderSamples = [];
                startStim = nan;
                stimStarted = false;
                stimEndTime = nan;
        
                % ---------------------  TRIAL ONSET  ---------------------
                startTrial = GetSecs;
                trialRow.TrialStart_time = startTrial - expTime_start;
        
                % DISPLAY Start Trial Text
                printStartTrial(app, windowPtr);
        
                % SET the speed for the linear stage
                cmd = sprintf("S %d\n", speedStage);
                writeline(app.LinearStage_motor, cmd);
        
                curClock = GetSecs;
                while (GetSecs - curClock) < 0.5
                    drawnow limitrate
                    if app.stopGUI == 1
                        app.stopGUI = 0;
                        error('Experiment stopped');
                    end
                end
        
                % SET the acceleration for the linear stage
                cmd = sprintf("A %d\n", accStage);
                writeline(app.LinearStage_motor, cmd);
        
                curClock = GetSecs;
                while (GetSecs - curClock) < 0.25
                    drawnow limitrate
                    if app.stopGUI == 1
                        app.stopGUI = 0;
                        error('Experiment stopped');
                    end
                end
        
                % ---------------------  PASSIVE MOTION CUE ---------------------
                printPassiveMotionCue(app, windowPtr, w, w1, no_motion_flag);
        
                curClock = GetSecs;
                while (GetSecs - curClock) < 0.25
                    drawnow limitrate
                    if app.stopGUI == 1
                        app.stopGUI = 0;
                        error('Experiment stopped');
                    end
                end
        
                % ---------------------  MOTOR / STIM PREP ---------------------
                write(app.rotation_and_spin_motor, sprintf("%d %d %d\n", 3, ...
                    flipSpeed * trialRow.StimSpeed_arduino, ...
                    trialRow.StimDuration_arduino), "string");
        
                curClock = GetSecs;
                while (GetSecs - curClock) < 0.5
                    drawnow limitrate
                    if app.stopGUI == 1
                        app.stopGUI = 0;
                        error('Experiment stopped');
                    end
                end
        
                % ---------------------  PASSIVE ARM MOVEMENT ---------------------
                startArmMov = GetSecs;
                trialRow.Arm_Mov_Onset = startArmMov - expTime_start;
        
                flushRotaryEncoderBuffer(app);
                zeroRotaryEncoder(app);
                pause(0.05);
        
                cmd = sprintf("M %d\n", stepsStage);
                writeline(app.LinearStage_motor, cmd);
        
                stimDurSec = trialRow.StimDuration_arduino / 1000;
                app.stimLagSec = 0.25;
        
                while true
                    elapsed = GetSecs - startArmMov;
        
                    if ~stimStarted && elapsed >= app.stimLagSec
                        startStim = GetSecs;
                        trialRow.StimOnset = startStim - expTime_start;
                        stimEndTime = startStim + stimDurSec;
                        stimStarted = true;
        
                        printResponsePrompt(app, windowPtr);
        
                        indentLoc = app.curIndent;
                        write(app.indentationActuator, sprintf("%d %d\n", 1, indentLoc), "string");
                    end
        
                    % collect encoder from passive arm movement onset onward
                    newSamples = readAvailableEncoderSamples(app);
                    if ~isempty(newSamples)
                        encoderSamples = [encoderSamples; newSamples]; %#ok<AGROW>
                        updateCurrentSpeedDisplay(app, encoderSamples);
                    end
        
                    if stimStarted
                        [~, ~, buttons] = GetMouse;
                        if any(buttons)
                            buttonPushed = find(buttons ~= 0, 1);
                            respTime = GetSecs - startStim;
                            printCenteredMessage(app, windowPtr, 'Wait...');
                            break;
                        end
        
                        if GetSecs >= stimEndTime
                            break;
                        end
                    end
        
                    drawnow limitrate
                    if app.stopGUI == 1
                        app.stopGUI = 0;
                        error('Experiment stopped');
                    end
                end
        
                % MOVE the wheel up
                indentLoc = app.indentZero;
                write(app.indentationActuator, sprintf("%d %d\n", 1, indentLoc), "string");
        
                % ---------------------  STIMULUS DONE ---------------------
                trialRow.EncoderSamples = {encoderSamples};
                trialRow.MeasuredSpeed_cm_s = computeAverageEncoderVelocity(app, encoderSamples);
                updateSpeedHistogram(app, encoderSamples);
        
                if stimStarted && isempty(buttonPushed)
                    [buttonPushed, respTime] = waitForResponse(app, windowPtr, startStim);
                elseif ~stimStarted
                    trialRow.StimOnset = nan;
                end
        
                trialRow.Response = buttonPushed;
                trialRow.ReactionTime = respTime;
                myTrialHistory = printResponseInfo(app, myTrialHistory, buttonPushed);
        
                curClock = GetSecs;
                while (GetSecs - curClock) < 0.5
                    drawnow limitrate
                    if app.stopGUI == 1
                        app.stopGUI = 0;
                        error('Experiment stopped');
                    end
                end
        
            catch ME
               rethrow(ME);
            end
        end


    function ok = validateExperimentStart(app)
        ok = false;
        if ~app.motorsLoaded
            uialert(app.UIFigure, 'Motors have not been loaded', 'Error starting experiment');
        elseif isempty(app.fileLoaded)
            uialert(app.UIFigure, 'Experimental file is not loaded', 'Error starting experiment');
        elseif isempty(app.participantID)
            uialert(app.UIFigure, 'Participant ID is not set', 'Error starting experiment');
        else
            ok = true;
        end
    end
 
        function moveToNextStartPosition(app, experimentTrial_Matrix, n)
        nextArm_startPosition = experimentTrial_Matrix.Arm_mov_StartPosition_arduino(n + 1);
    
        stepsStage = experimentTrial_Matrix.Arm_Mov_Steps_arduino(n);
    
        if stepsStage > 0
            if nextArm_startPosition == 0
                stepsStage = abs(app.maxTravel) - stepsStage;
            else
                stepsStage = -1 * stepsStage;
            end
        elseif stepsStage < 0
            if nextArm_startPosition == 0
                stepsStage = -1 * stepsStage;
            else
                stepsStage = (-1 * app.maxTravel) - stepsStage;
            end
        else
            stepsStage = nextArm_startPosition - app.mycurStartLoc;
        end
    
        app.mycurStartLoc = nextArm_startPosition;
    
        curClock = GetSecs;
        while (GetSecs - curClock) < 0.5
            drawnow limitrate
            if app.stopGUI == 1
                app.stopGUI = 0;
                error('Experiment stopped');
            end
        end
    
        accStage = 2600;
        cmd = sprintf("A %d\n", accStage);
        writeline(app.LinearStage_motor, cmd);
        pause(.1);
    
        speedStage = 2600;
        cmd = sprintf("S %d\n", speedStage);
        writeline(app.LinearStage_motor, cmd);
        pause(.1);
    
        cmd = sprintf("M %d\n", stepsStage);
        writeline(app.LinearStage_motor, cmd);
    end
   
    function runInterTrialInterval(app, interTrialInterval, outName, experimentTrial_Matrix)
        curClock = GetSecs;
        dataSaved = 0;
    
        while GetSecs - curClock < interTrialInterval
            if dataSaved == 0
                save(outName, 'experimentTrial_Matrix')
                dataSaved = 1;
            end
    
            drawnow limitrate
            if app.stopGUI == 1
                errordlg('Experiment stopped', 'Stopped Exp')
                app.stopGUI = 0;
                error('Experiment stopped');
            end
        end
    end
    
    function [activeBehavior, activeBehaviorFile] = loadPairedActiveBehaviorFile(app, pairID)
        behaviorDir = fullfile(app.curFolder, [app.participantID '_data']);
    
        pattern = sprintf('*pair_%02d*ACTIVE*_behavior.mat', pairID);
        files = dir(fullfile(behaviorDir, pattern));
    
        if isempty(files)
            error('No ACTIVE behavior file found for PairID %d', pairID);
        elseif numel(files) > 1
            error('Multiple ACTIVE behavior files found for PairID %d. Make filename matching more specific.', pairID);
        end
        activeBehaviorFile = fullfile(files(1).folder, files(1).name);
        S = load(activeBehaviorFile);
    
        if ~isfield(S, 'experimentTrial_Matrix')
            error('File does not contain experimentTrial_Matrix: %s', activeBehaviorFile);
        end
        activeBehavior = S.experimentTrial_Matrix;
    end
    function vel_cm_s = computeInstantaneousSpeeds(app, encoderSamples)
        if size(encoderSamples,1) < 2
            vel_cm_s = [];
            return
        end
    
        dt = diff(encoderSamples(:,1)) ./ 1000;   % s
        dx = diff(encoderSamples(:,3));           % cm
    
        vel_cm_s = abs(dx ./ dt);
        vel_cm_s(dt <= 0) = NaN;
        vel_cm_s = vel_cm_s(~isnan(vel_cm_s) & isfinite(vel_cm_s));
    end
    
    function updateCurrentSpeedDisplay(app, encoderSamples)
        vel_cm_s = computeInstantaneousSpeeds(app, encoderSamples);
    
        if isempty(vel_cm_s)
            app.CurrentArmMovementNALabel.Text = 'Current Arm Movement: N/A';
        else
            app.CurrentArmMovementNALabel.Text = sprintf('Current Arm Movement: %.2f cm/s', vel_cm_s(end));
        end
        drawnow limitrate
    end
    
    function updateSpeedHistogram(app, encoderSamples)
        vel_cm_s = computeInstantaneousSpeeds(app, encoderSamples);
    
        cla(app.UIAxes);
    
        if isempty(vel_cm_s)
            title(app.UIAxes, 'Arm movements')
            xlabel(app.UIAxes, 'Movement Speed (cm/s)')
            ylabel(app.UIAxes, 'Counts')
            return
        end
    
        histogram(app.UIAxes, vel_cm_s, 12, ...
            'FaceColor', [0.2 0.5 0.9], ...
            'EdgeColor', 'k');
    
        title(app.UIAxes, 'Hand speed histogram')
        xlabel(app.UIAxes, 'Movement Speed (cm/s)')
        ylabel(app.UIAxes, 'Counts')
    end
    
    function resetSpeedDisplay(app)
        cla(app.UIAxes);
        title(app.UIAxes, 'Arm movements')
        xlabel(app.UIAxes, 'Movement Speed (cm/s)')
        ylabel(app.UIAxes, 'Counts')
        app.CurrentArmMovementNALabel.Text = 'Current Arm Movement: N/A';
    end

    function results = motionDirectionExperiment(app, windowPtr)
        results = [];
        app.participantID = app.ParticipantsIDEditField.Value;
        clc
    
        Screen('TextSize', windowPtr, 40);
        Screen('TextFont', windowPtr, 'Arial');
    
        % CHECK FOR ERRORS
        if ~validateExperimentStart(app)
            return;
        end
    
        % START THE EXPERIMENT
        % LOAD experimental file
        experimentTrial_Matrix = app.fileLoaded.TrialStim_param;
        nTrials = size(experimentTrial_Matrix, 1);
        app.curPairId = experimentTrial_Matrix.PairID(1);
 
    
        % INIT camera
        % if isempty(app.cam)
        %     app.cam = webcam;
        % end
        % 
        %-----------------------------
        % SET lin stage acceleration and speed
        accStage = 2600;
        cmd = sprintf("A %d\n", accStage);
        writeline(app.LinearStage_motor, cmd);
        pause(.1);
    
        speedStage = 2600;
        cmd = sprintf("S %d\n", speedStage);
        writeline(app.LinearStage_motor, cmd);
        pause(.1);
    
        % MOVE the arm to start position
        stepsStage = experimentTrial_Matrix.Arm_mov_StartPosition_arduino(1);
        cmd = sprintf("M %d\n", stepsStage);
        writeline(app.LinearStage_motor, cmd);
        app.mycurStartLoc = stepsStage;
    
        % MAKE directory to save behavior files
        [saveDir, outName] = getBehaviorSavePath(app);
            
        %-----------------------------
        % DISPLAY instructions
        textString = 'Judge if stimulus moves left or right relative to your trunk';
        DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
        Screen('Flip', windowPtr);
        WaitSecs(4);
    
        app.TrialhistoryTextArea.Value = "";
        myTrialHistory = {};
        expTime_start = GetSecs;
    
        textString = 'Experiment will begin in 3 seconds';
        DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
        Screen('Flip', windowPtr);
    
        curClock = GetSecs;
        while GetSecs - curClock < 2
            drawnow limitrate
            if app.stopGUI == 1
                errordlg('Experiment stopped', 'Stopped Exp')
                app.stopGUI = 0;
                return;
            end
        end
    
        % Interval before start
        textString = '';
        DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
        Screen('Flip', windowPtr);
        WaitSecs(1);
    
        %-----------------------------
        % TRIALS START
        for n = 1:nTrials
            app.TrialIndex = n;
            isActive = experimentTrial_Matrix.IsActive(n) == 1;
        
            try
                trialRow = experimentTrial_Matrix(n,:);
        
                if isActive
                    writeline(app.LinearStage_motor, "E 1");
                    pause(0.4);
                   [trialRow, myTrialHistory] = runActiveTrial(app, windowPtr, trialRow, n, expTime_start, myTrialHistory);
                else
                   writeline(app.LinearStage_motor, "E 0");
                   pause(0.4);
                   [trialRow, myTrialHistory] = runPassiveTrial(app, windowPtr, trialRow, n, expTime_start, myTrialHistory);
                end
                experimentTrial_Matrix(n,:) = trialRow;
        
                if n < nTrials
                    moveToNextStartPosition(app, experimentTrial_Matrix, n);
                end
                runInterTrialInterval(app, trialRow.InterTrialInterval, outName, experimentTrial_Matrix);
        
            catch ME
                try
                    if isActive
                        stopVideoRecording(app);
                    end
                catch
                end
                %save behavioral data
                save(outName, 'experimentTrial_Matrix');
                errordlg(sprintf('Block stopped on trial %d:\n%s', n, ME.message), 'Block Stopped');
                return
            end
        end
        % BLOCK END
        % rotate stimulus to center point
        myAngle = app.rotationCtrAngle;
        correctDirection(app.rotation_and_spin_motor, app.curAngle, myAngle);
        app.curAngle = myAngle;
    
        % move the stage to the left side
        stepsStage = experimentTrial_Matrix.Arm_Mov_Steps_arduino(end);
        nextArm_startPosition = 0;
        if stepsStage > 0
            if nextArm_startPosition == 0
                stepsStage = abs(app.maxTravel) - stepsStage;
            else
                stepsStage = -1 * stepsStage;
            end
        elseif stepsStage < 0
            if nextArm_startPosition == 0
                stepsStage = -1 * stepsStage;
            else
                stepsStage = (-1 * app.maxTravel) - stepsStage;
            end
        else
            stepsStage = nextArm_startPosition - app.mycurStartLoc;
        end
    
        app.mycurStartLoc = nextArm_startPosition;
    
        accStage = 2600;
        cmd = sprintf("A %d\n", accStage);
        writeline(app.LinearStage_motor, cmd);
        pause(.1);
    
        speedStage = 2600;
        cmd = sprintf("S %d\n", speedStage);
        writeline(app.LinearStage_motor, cmd);
        pause(.1);
    
        cmd = sprintf("M %d\n", stepsStage);
        disp('moving linear stage');
        writeline(app.LinearStage_motor, cmd);
    
        app.mycurLoc = nextArm_startPosition;
    
        if ~isempty(app.cam)
            clear app.cam
            app.cam = [];
        end
    
        results = experimentTrial_Matrix;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            

            clc; close all

            % PsychDefaultSetup(2);
            app.RunButton.Enable = 'off';
            app.LoadMotorsCheckBox.Enable = 'off';
            app.PauseButton.Enable = 'off';
            app.PracticetrialsButton.Enable = 'off';

            screens = Screen('Screens');
            secondMonitor = max(screens);

            [windowPtr, rect] = Screen('OpenWindow', secondMonitor, [0 0 0]);

            %Set text properties (optional)
            Screen('TextSize', windowPtr, 40);
            Screen('TextFont', windowPtr, 'Arial');

            % %----------------
            % stepsStage = app.StepsEditField.Value;
            % if app.RightButton.Value == 1
            %     stepsStage = -1 * stepsStage;
            % else
            % end
            % cmd = sprintf("M %d\n", stepsStage);
            % writeline(app.LinearStage_motor, cmd);
            % %-------------------

            if app.DirectionButton.Value == 1 %run motion direction experiment
                motionDirectionExperiment(app, windowPtr);
            else %run motion speed experiment
            end

            app.RunButton.Enable = 'on';
            app.LoadMotorsCheckBox.Enable = 'on';
            app.PauseButton.Enable = 'on';
            app.PracticetrialsButton.Enable = 'on';

            % Close the window
            Screen('CloseAll');

        end

        % Value changed function: LoadMotorsCheckBox
        function LoadMotorsCheckBoxValueChanged(app, event)
            value = app.LoadMotorsCheckBox.Value;
            
            if value == 1

                try
                    app.rotation_and_spin_motor = serialport(app.rotation_and_spin_motor_comPort, app.rotation_and_spin_motor_Rate);  % Stepper  / Herkulex
                    app.indentationActuator = serialport(app.indentationActuator_comPort, app.indentationActuator_Rate);  % Linear actuator
                    app.LinearStage_motor = serialport(app.LinearStage_motor_comPort, app.LinearStage_motor_Rate);  % Linear stage
                    app.rotaryEncoder = serialport( ...
                        app.rotaryEncoder_comPort, ...
                        app.rotaryEncoder_Rate);
                    
                    configureTerminator(app.rotaryEncoder,"LF");
                    flush(app.rotaryEncoder);
                    pause(0.25);

                    configureTerminator(app.LinearStage_motor, "LF");
                    flush(app.LinearStage_motor);
                    pause(.25);

                    %------------------
                    speedStage = app.SpeedEditField.Value;
                    cmd = sprintf("S %d\n", speedStage);
                    writeline(app.LinearStage_motor, cmd);
                    pause(.25);

                    accStage = app.SpeedEditField.Value;
                    cmd = sprintf("A %d\n", accStage);
                    writeline(app.LinearStage_motor, cmd);
                    pause(.25);
                    %------------------

                    %push the linear actuator to its most retracted position
                    write(app.indentationActuator, sprintf("%d %d\n", 1, app.baseActuatorPos), "string");
                    app.indentZero = app.baseActuatorPos;
                    app.curIndent = app.indentZero;
                    pause(.25);

                    helpdlg('Arduino-controled motors connected', 'Success');

                    app.motorsLoaded = 1;

                catch
                    app.rotaryEncoder = [];
                    app.rotation_and_spin_motor = [];
                    app.indentationActuator = [];
                    app.LinearStage_motor = [];
                    app.motorsLoaded = [];

                    errordlg('Error connecting to Arduino-controled devices', 'Devices not connected');
                end

            else
                app.rotaryEncoder = [];
                app.rotation_and_spin_motor = [];
                app.indentationActuator = [];
                app.LinearStage_motor = [];
                app.motorsLoaded = [];

            end
        end

        % Value changed function: CreateTrialsButton
        function CreateTrialsButtonValueChanged(app, event)
              
            if ~isempty(app.ParticipantsIDEditField.Value)
                myDir = uigetdir;
                if myDir ~= 0                    
                    app.curFolder = myDir;
                    app.participantID = app.ParticipantsIDEditField.Value;

                    if app.DirectionButton.Value == 1
                        taskType = 'Motion_Direction';
                    else
                        taskType = 'Motion_Speed';
                    end
                    %generating trials
                    generate_trials_MotionDirection_with_passive(app.curFolder, app.participantID, taskType);

                else                    
                end

            else
                errordlg('Participant ID missing', 'Error generating trials');
            end

            focus(app.UIFigure);
        end

        % Value changed function: PracticetrialsButton
        function PracticetrialsButtonValueChanged(app, event)
            value = app.PracticetrialsButton.Value;
            
            if ~isempty(app.rotation_and_spin_motor)

                WaitSecs(1);

                myAngle = app.RotationEditField.Value;
                [myAngle,flipSpeed] = correctDirection(app.rotation_and_spin_motor,app.curAngle,myAngle);

                write(app.rotation_and_spin_motor, sprintf("%d %d %d\n", 3, flipSpeed * 600, 2000), "string");

                startStim = GetSecs;
                indentLoc = app.curIndent;
                write(app.indentationActuator, sprintf("%d %d\n", 1, indentLoc), "string");
                buttonPushed = [];
                respTime = [];
                while (GetSecs - startStim) < 2
                    drawnow limitrate
                end
                indentLoc = app.indentZero;
                write(app.indentationActuator, sprintf("%d %d\n", 1, indentLoc), "string"); %retracting stimulus

                app.curAngle = myAngle;
            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end
        end

        % Value changed function: MoveButton
        function MoveButtonValueChanged(app, event)
                        
            if ~isempty(app.LinearStage_motor)

                %------------------
                speedStage = app.SpeedEditField.Value;
                cmd = sprintf("S %d\n", speedStage);
                writeline(app.LinearStage_motor, cmd);
                pause(.25);

                accStage = app.SpeedEditField.Value;
                cmd = sprintf("A %d\n", accStage);
                writeline(app.LinearStage_motor, cmd);
                pause(.25);

                stepsStage = app.StepsEditField.Value;
                if app.RightButton.Value == 1
                    stepsStage = -1 * stepsStage;
                else
                end
                cmd = sprintf("M %d\n", stepsStage);
                writeline(app.LinearStage_motor, cmd);
                %------------------
                % app.mycurLoc = app.mycurLoc + stepsStage;
                

            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end

        end

        % Value changed function: RotatemotorButton
        function RotatemotorButtonValueChanged(app, event)
            if ~isempty(app.rotation_and_spin_motor)

                %rotating stimulus to correct direction               
                % app.newAngle = app.RotationEditField.Value;
                % correctDirection(app.rotation_and_spin_motor,app.rotationCtrAngle-app.curAngle,app.newAngle);
                % app.curAngle = app.rotationCtrAngle - app.newAngle;

                newRotation = app.RotationEditField.Value;
                correctDirection_forcalibration(app.rotation_and_spin_motor, app.curAngle, newRotation);
                app.curAngle = app.curAngle - newRotation;
                pause(.1);

            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end
            
        end

        % Value changed function: Set90Button
        function Set90ButtonValueChanged(app, event)
           
            if ~isempty(app.rotation_and_spin_motor)

                %rotating stimulus to correct direction               
                app.rotationCtrAngle = 90;                
                app.curAngle = app.rotationCtrAngle;
                helpdlg('Rotation value at 90º set', 'Success');


            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end
        end

        % Value changed function: MoveUpDownButton
        function MoveUpDownButtonValueChanged(app, event)
            if ~isempty(app.indentationActuator)

                indentSteps = app.IndentEditField.Value;
                indentSteps = indentSteps * (app.maxActuatorPos - app.baseActuatorPos)/app.maxActuatorDisp;
                newLoc = round(app.curIndent + indentSteps);

                %if desired movement is within the range then move the
                %actuator
                if (newLoc >= app.baseActuatorPos) & (newLoc <= app.maxActuatorPos)
                   write(app.indentationActuator, sprintf("%d %d\n", 1, newLoc), "string");
                   app.curIndent = newLoc;
                else
                    errordlg('The movement is beyond the range of the actuator', 'Error moving actuator');
                end
                
                pause(1);
     
            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end


        end

        % Value changed function: MovefullyupButton
        function MovefullyupButtonValueChanged(app, event)
            if ~isempty(app.indentationActuator)

                %move actuator to the base posiution (the least extended
                %pos)
                write(app.indentationActuator, sprintf("%d %d\n", 1, app.baseActuatorPos), "string");
                app.curIndent = app.baseActuatorPos;
                app.indentZero = app.curIndent;
                pause(1);

            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end
            
        end

        % Value changed function: SetzeropointButton
        function SetzeropointButtonValueChanged(app, event)
            if ~isempty(app.indentationActuator)

                indentSteps = -2;
                indentSteps = indentSteps * (app.maxActuatorPos - app.baseActuatorPos)/app.maxActuatorDisp;
                newLoc = round(app.curIndent + indentSteps);

                %if desired movement is within the range then move the
                %actuator
                if (newLoc >= app.baseActuatorPos) & (newLoc <= app.maxActuatorPos)
                   write(app.indentationActuator, sprintf("%d %d\n", 1, newLoc), "string");
                   app.indentZero = newLoc;                
                   helpdlg('Zero-based position set', 'Success');
                else
                    errordlg('The movement is beyond the range of the actuator', 'Error moving actuator');
                end

                
                pause(1);
                
            else
                errordlg('Zero point position not set', 'Devices not connected');
            end
            
        end

        % Value changed function: LoadExperimentFileButton
        function LoadExperimentFileButtonValueChanged(app, event)
            [fileToLoad,parDir,indx] = uigetfile();
       

            if indx ~= 0

                try
                    app.fileName = fileToLoad;
                    app.curFolder = parDir;
                    app.fileLoaded = load(fullfile(app.curFolder, app.fileName));
                    app.ExperimentFileNALabel.Text = ['Experiment File: ' app.fileName];
                    if isfield(app.fileLoaded, 'TrialStim_param')
                        app.curPairId = app.fileLoaded.TrialStim_param.PairID(1);
                    end
             
                catch
                    app.fileName = [];
                    app.curFolder = [];
                    app.fileLoaded = [];
                    app.ExperimentFileNALabel.Text = 'Experiment File: N/A';
                end

            else
            end

            focus(app.UIFigure);
            
        end

        % Value changed function: SetstartPosButton
        function SetstartPosButtonValueChanged(app, event)
            if ~isempty(app.rotation_and_spin_motor)
                app.mycurLoc = 0;
                helpdlg('Starting hand position set', 'Success');
            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end
            
            
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)

            app.RunButton.Enable = 'on';
            app.LoadMotorsCheckBox.Enable = 'on';
            app.PauseButton.Enable = 'on';
            app.PracticetrialsButton.Enable = 'on';

            app.stopGUI = 1;

        end

        % Value changed function: LinearStageMotorSwitch
        function LinearStageMotorSwitchValueChanged(app, event)
            value = app.LinearStageMotorSwitch.Value;
            if isempty(app.LinearStage_motor)
                uialert(app.UIFigure, 'Linear stage motor is not connected.', 'Motor Control Error');
                return;
            end
        
            try
                if strcmpi(value, 'On')
                    % ON = engaged
                    writeline(app.LinearStage_motor, "E 0");
                else
                    % OFF = free
                    writeline(app.LinearStage_motor, "E 1");
                end
                pause(0.1);
            catch ME
                uialert(app.UIFigure, ME.message, 'Motor Control Error');
            end
        end

        % Button pushed function: TestEncoderButton
        function TestEncoderButtonPushed(app, event)
            flush(app.rotaryEncoder)
        
            disp("Reading encoder...")
        
            tStart = tic;
        
            while toc(tStart) < 5
        
                if app.rotaryEncoder.NumBytesAvailable > 0
        
                    line = readline(app.rotaryEncoder);
        
                    disp(line)
        
                end
            end 
        end

        % Button pushed function: LoadPairedPassiveBlockButton
        function LoadPairedPassiveBlockButtonPushed(app, event)
            if isempty(app.fileLoaded) || ~isfield(app.fileLoaded, 'TrialStim_param')
                uialert(app.UIFigure, 'Load an ACTIVE block first.', 'No block loaded');
                return;
            end
        
            app.participantID = app.ParticipantsIDEditField.Value;
            if isempty(app.participantID)
                uialert(app.UIFigure, 'Participant ID is not set.', 'Missing participant');
                return;
            end
        
            pairID = app.fileLoaded.TrialStim_param.PairID(1);
            app.curPairId = pairID;
        
            [activeBehavior, activeBehaviorFile] = loadPairedActiveBehaviorFile(app, pairID);
            passiveTrialFile = findPairedPassiveTrialFile(app, pairID);
        
            S = load(passiveTrialFile);
            if isfield(S, 'TrialStim_param')
                passiveTrials = S.TrialStim_param;
            elseif isfield(S, 'experimentTrial_Matrix')
                passiveTrials = S.experimentTrial_Matrix;
            else
                error('File does not contain TrialStim_param or experimentTrial_Matrix: %s', passiveTrialFile);
            end
        
            isFast = activeBehavior.Arm_Mov_Acc_arduino == app.fastMotionCommanded;
            isSlow = activeBehavior.Arm_Mov_Acc_arduino == app.slowMotionCommanded;
        
            avgFast = mean(activeBehavior.MeasuredSpeed_cm_s(isFast), 'omitnan');
            avgSlow = mean(activeBehavior.MeasuredSpeed_cm_s(isSlow), 'omitnan');
        
            [new_speed_fast, new_acc_fast, new_dist_fast, ...
             new_speed_slow, new_acc_slow, new_dist_slow] = ...
                generatePassiveCommands(activeBehavior, app.fastMotionCommanded, app.slowMotionCommanded);
        
            avgTableFile = fullfile(app.curFolder, sprintf('%s_avg_speeds.csv', app.participantID));
        
            newRow = table( ...
                string(app.participantID), ...
                pairID, ...
                avgFast, ...
                avgSlow, ...
                new_speed_fast, ...
                new_speed_slow, ...
                string(datetime('now','Format','yyyy-MM-dd HH:mm:ss')), ...
                'VariableNames', {'ParticipantID','PairID','AvgFast_cm_s','AvgSlow_cm_s', ...
                                  'NewFastSpeed','NewSlowSpeed','Timestamp'});
        
            if isfile(avgTableFile)
                T = readtable(avgTableFile);
                idx = T.PairID == pairID;
                if any(idx)
                    T(idx,:) = newRow;
                else
                    T = [T; newRow];
                end
            else
                T = newRow;
            end
        
            writetable(T, avgTableFile);
        
            fastMask = passiveTrials.Arm_Mov_Acc_arduino == app.fastMotionCommanded;
            slowMask = passiveTrials.Arm_Mov_Acc_arduino == app.slowMotionCommanded;
        
            passiveTrials.Arm_Mov_Speed_arduino(fastMask) = new_speed_fast;
            passiveTrials.Arm_Mov_Acc_arduino(fastMask)   = new_acc_fast;
            passiveTrials.Arm_Mov_Steps_arduino(fastMask) = ...
                sign(passiveTrials.Arm_Mov_Steps_arduino(fastMask)) .* abs(new_dist_fast);
        
            passiveTrials.Arm_Mov_Speed_arduino(slowMask) = new_speed_slow;
            passiveTrials.Arm_Mov_Acc_arduino(slowMask)   = new_acc_slow;
            passiveTrials.Arm_Mov_Steps_arduino(slowMask) = ...
                sign(passiveTrials.Arm_Mov_Steps_arduino(slowMask)) .* abs(new_dist_slow);
        
            % keep absolute-distance column consistent
            passiveTrials.Arm_Mov_StepsAbs_arduino = abs(passiveTrials.Arm_Mov_Steps_arduino);
        
            % recompute start positions using the same rule as regular trial generation
            startAtZeroMask = passiveTrials.Arm_mov_StartPosition_arduino == 0;
            startAtOtherSideMask = ~startAtZeroMask;
        
            newMaxStep = max(passiveTrials.Arm_Mov_StepsAbs_arduino);
        
            passiveTrials.Arm_mov_StartPosition_arduino(startAtZeroMask) = 0;
            passiveTrials.Arm_mov_StartPosition_arduino(startAtOtherSideMask) = -newMaxStep;
        
            TrialStim_param = passiveTrials;
            save(passiveTrialFile, 'TrialStim_param');
        
            app.fileLoaded = struct('TrialStim_param', TrialStim_param);
        
            [~, baseName, ext] = fileparts(passiveTrialFile);
            app.fileName = [baseName ext];
            app.ExperimentFileNALabel.Text = ['Experiment File: ' app.fileName];
        
            helpdlg(sprintf(['Loaded pair %d\nACTIVE behavior:\n%s\n\n' ...
                'PASSIVE block updated and ready to run:\n%s'], ...
                pairID, activeBehaviorFile, passiveTrialFile), ...
                'Paired files loaded');
        end


            function passiveTrialFile = findPairedPassiveTrialFile(app, pairID)
                trialsDir = app.curFolder;
            
                if ~isfolder(trialsDir)
                    error('Participant folder not found: %s', trialsDir);
                end
            
                pattern = sprintf('*pair_%02d*PASSIVE*.mat', pairID);
                files = dir(fullfile(trialsDir, pattern));
            
                files = files(~[files.isdir]);
            
                if isempty(files)
                    error('No PASSIVE trial file found for PairID %d in %s', pairID, trialsDir);
                elseif numel(files) > 1
                    names = strjoin({files.name}, ', ');
                    error('Multiple PASSIVE trial files found for PairID %d in %s: %s', ...
                        pairID, trialsDir, names);
                end
            
                passiveTrialFile = fullfile(files(1).folder, files(1).name);
      
        end

        % Button pushed function: fillbehaviorfilefordebuggingButton
        function fillbehaviorfilefordebuggingButtonPushed(app, event)
        
            if isempty(app.fileLoaded)
                uialert(app.UIFigure, 'Load an ACTIVE block first.', 'No block loaded');
                return;
            end
        
            if isempty(app.ParticipantsIDEditField.Value)
                uialert(app.UIFigure, 'Participant ID is not set.', 'Missing participant');
                return;
            end
        
            app.participantID = app.ParticipantsIDEditField.Value;
        
            experimentTrial_Matrix = app.fileLoaded.TrialStim_param;
        
            if ~all(experimentTrial_Matrix.IsActive == 1)
                uialert(app.UIFigure, 'Loaded block is not an ACTIVE block.', 'Wrong block type');
                return;
            end
        
            nTrials = size(experimentTrial_Matrix, 1);
        
            for n = 1:nTrials
                experimentTrial_Matrix.TrialStart_time(n) = n;
                experimentTrial_Matrix.Arm_Mov_Onset(n) = n + 0.25;
                experimentTrial_Matrix.StimOnset(n) = n + 0.50;
                experimentTrial_Matrix.Response(n) = 1;
                experimentTrial_Matrix.ReactionTime(n) = 0.6;
        
                if experimentTrial_Matrix.Arm_Mov_Acc_arduino(n) == app.slowMotionCommanded
                    experimentTrial_Matrix.MeasuredSpeed_cm_s(n) = 1.36;
                elseif experimentTrial_Matrix.Arm_Mov_Acc_arduino(n) == app.fastMotionCommanded
                    experimentTrial_Matrix.MeasuredSpeed_cm_s(n) = 7.56;
                else
                    experimentTrial_Matrix.MeasuredSpeed_cm_s(n) = 0;
                end
        
                experimentTrial_Matrix.EncoderSamples{n} = [];
            end
        
            [~, outName] = getBehaviorSavePath(app);
            save(outName, 'experimentTrial_Matrix');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Arm movements')
            xlabel(app.UIAxes, 'Movement Speed (cm/s)')
            ylabel(app.UIAxes, 'Counts')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.MovestagePanel = uipanel(app.UIFigure);
            app.MovestagePanel.Title = 'Move stage';
            app.SpeedEditFieldLabel = uilabel(app.MovestagePanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.MovestagePanel, 'numeric');
            app.SpeedEditField.Limits = [0 2600];
            app.SpeedEditField.Value = 2600;
            app.StepsEditFieldLabel = uilabel(app.MovestagePanel);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.MovestagePanel, 'numeric');
            app.StepsEditField.Limits = [0 500];
            app.StepsEditField.Value = 300;
            app.DirectionButtonGroup = uibuttongroup(app.MovestagePanel);
            app.DirectionButtonGroup.Title = 'Direction';
            app.LeftButton = uiradiobutton(app.DirectionButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.DirectionButtonGroup);
            app.RightButton.Text = 'Right';
            app.MoveButton = uibutton(app.MovestagePanel, 'state');
            app.MoveButton.ValueChangedFcn = createCallbackFcn(app, @MoveButtonValueChanged, true);
            app.MoveButton.Text = 'Move';
            app.SetstartPosButton = uibutton(app.MovestagePanel, 'state');
            app.SetstartPosButton.ValueChangedFcn = createCallbackFcn(app, @SetstartPosButtonValueChanged, true);
            app.SetstartPosButton.Text = 'Set start Pos.';
            app.ParticipantsIDEditFieldLabel = uilabel(app.UIFigure);
            app.ParticipantsIDEditFieldLabel.Text = 'Participants ID #';
            app.ParticipantsIDEditField = uieditfield(app.UIFigure, 'text');
            app.CentermotorsPanel = uipanel(app.UIFigure);
            app.CentermotorsPanel.Title = 'Center motors';
            app.RotationEditFieldLabel = uilabel(app.CentermotorsPanel);
            app.RotationEditFieldLabel.Text = 'Rotation';
            app.RotationEditField = uieditfield(app.CentermotorsPanel, 'numeric');
            app.RotationEditField.Limits = [-180 180];
            app.RotatemotorButton = uibutton(app.CentermotorsPanel, 'state');
            app.RotatemotorButton.ValueChangedFcn = createCallbackFcn(app, @RotatemotorButtonValueChanged, true);
            app.RotatemotorButton.Text = 'Rotate motor';
            app.MoveUpDownButton = uibutton(app.CentermotorsPanel, 'state');
            app.MoveUpDownButton.ValueChangedFcn = createCallbackFcn(app, @MoveUpDownButtonValueChanged, true);
            app.MoveUpDownButton.Text = 'Move Up/Down';
            app.IndentEditFieldLabel = uilabel(app.CentermotorsPanel);
            app.IndentEditFieldLabel.Text = 'Indent';
            app.IndentEditField = uieditfield(app.CentermotorsPanel, 'numeric');
            app.IndentEditField.Limits = [-26 26];
            app.Set90Button = uibutton(app.CentermotorsPanel, 'state');
            app.Set90Button.ValueChangedFcn = createCallbackFcn(app, @Set90ButtonValueChanged, true);
            app.Set90Button.Text = 'Set 90º';
            app.SetzeropointButton = uibutton(app.CentermotorsPanel, 'state');
            app.SetzeropointButton.ValueChangedFcn = createCallbackFcn(app, @SetzeropointButtonValueChanged, true);
            app.SetzeropointButton.Text = 'Set zero point';
            app.MovefullyupButton = uibutton(app.CentermotorsPanel, 'state');
            app.MovefullyupButton.ValueChangedFcn = createCallbackFcn(app, @MovefullyupButtonValueChanged, true);
            app.MovefullyupButton.Text = 'Move fully up';
            app.GeneratetrialsButtonGroup = uibuttongroup(app.UIFigure);
            app.GeneratetrialsButtonGroup.Title = 'Generate trials';
            app.DirectionButton = uiradiobutton(app.GeneratetrialsButtonGroup);
            app.DirectionButton.Text = 'Direction';
            app.DirectionButton.Value = true;
            app.SpeedButton = uiradiobutton(app.GeneratetrialsButtonGroup);
            app.SpeedButton.Text = 'Speed';
            app.ExperimentFileNALabel = uilabel(app.UIFigure);
            app.ExperimentFileNALabel.Text = 'Experiment File: N/A';
            app.TrialhistoryTextAreaLabel = uilabel(app.UIFigure);
            app.TrialhistoryTextAreaLabel.Text = 'Trial history';
            app.TrialhistoryTextArea = uitextarea(app.UIFigure);
            app.CreateTrialsButton = uibutton(app.UIFigure, 'state');
            app.CreateTrialsButton.ValueChangedFcn = createCallbackFcn(app, @CreateTrialsButtonValueChanged, true);
            app.CreateTrialsButton.Text = 'Create Trials';
            app.MotorControlPanel = uipanel(app.UIFigure);
            app.MotorControlPanel.Title = 'Motor Control';
            app.LinearStageMotorSwitchLabel = uilabel(app.MotorControlPanel);
            app.LinearStageMotorSwitchLabel.Text = 'Linear Stage Motor ';
            app.LinearStageMotorSwitch = uiswitch(app.MotorControlPanel, 'slider');
            app.LinearStageMotorSwitch.ValueChangedFcn = createCallbackFcn(app, @LinearStageMotorSwitchValueChanged, true);
            app.LoadMotorsCheckBox = uicheckbox(app.MotorControlPanel);
            app.LoadMotorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadMotorsCheckBoxValueChanged, true);
            app.LoadMotorsCheckBox.Text = '  Load Motors';
            app.TestEncoderButton = uibutton(app.UIFigure, 'push');
            app.TestEncoderButton.ButtonPushedFcn = createCallbackFcn(app, @TestEncoderButtonPushed, true);
            app.TestEncoderButton.Text = 'Test Encoder';
            app.CurrentArmMovementNALabel = uilabel(app.UIFigure);
            app.CurrentArmMovementNALabel.Text = 'Current Arm Movement: N/A';
            app.ExperimentControlPanel = uipanel(app.UIFigure);
            app.ExperimentControlPanel.Title = 'Experiment Control';
            app.RunButton = uibutton(app.ExperimentControlPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.PauseButton = uibutton(app.ExperimentControlPanel, 'state');
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.ExperimentControlPanel, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.PracticetrialsButton = uibutton(app.ExperimentControlPanel, 'state');
            app.PracticetrialsButton.ValueChangedFcn = createCallbackFcn(app, @PracticetrialsButtonValueChanged, true);
            app.PracticetrialsButton.Text = 'Practice trials';
            app.LoadPairedPassiveBlockButton = uibutton(app.UIFigure, 'push');
            app.LoadPairedPassiveBlockButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPairedPassiveBlockButtonPushed, true);
            app.LoadPairedPassiveBlockButton.Text = 'Load Paired Passive Block';
            app.LoadExperimentFileButton = uibutton(app.UIFigure, 'state');
            app.LoadExperimentFileButton.ValueChangedFcn = createCallbackFcn(app, @LoadExperimentFileButtonValueChanged, true);
            app.LoadExperimentFileButton.Text = 'Load Experiment File';
            app.fillbehaviorfilefordebuggingButton = uibutton(app.UIFigure, 'push');
            app.fillbehaviorfilefordebuggingButton.ButtonPushedFcn = createCallbackFcn(app, @fillbehaviorfilefordebuggingButtonPushed, true);
            app.fillbehaviorfilefordebuggingButton.Text = 'fill behavior file for debugging';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = with_encoder_motion_control_experiment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end